classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        APPLYA2HButton             matlab.ui.control.Button
        MEDIANButton_2             matlab.ui.control.Button
        AVERAGEButton_2            matlab.ui.control.Button
        MAXButton_2                matlab.ui.control.Button
        MINButton_2                matlab.ui.control.Button
        FILTERLabel_2              matlab.ui.control.Label
        OUTPUTIMAGE                matlab.ui.control.Image
        OUTPUTIMAGELabel           matlab.ui.control.Label
        INPUTIMAGELabel            matlab.ui.control.Label
        INPUTIMAGE                 matlab.ui.control.Image
        SETTRANSFERFUNCTIONButton  matlab.ui.control.Button
        EditFieldShift             matlab.ui.control.NumericEditField
        SHIFTButtonGroup           matlab.ui.container.ButtonGroup
        DOWNButton                 matlab.ui.control.RadioButton
        UPButton                   matlab.ui.control.RadioButton
        LEFTButton                 matlab.ui.control.RadioButton
        RIGHTButton                matlab.ui.control.RadioButton
        EQUALIZATIONButton         matlab.ui.control.Button
        STRETCHINGButton           matlab.ui.control.Button
        BlackSlider                matlab.ui.control.Slider
        BlackSliderLabel           matlab.ui.control.Label
        WhiteSilder                matlab.ui.control.Slider
        WhiteSilderSliderLabel     matlab.ui.control.Label
        RGBHSIButton               matlab.ui.control.Button
        CROPButton                 matlab.ui.control.Button
        Y2EditFieldCrop            matlab.ui.control.NumericEditField
        Y2EditFieldLabel           matlab.ui.control.Label
        X2EditFieldCrop            matlab.ui.control.NumericEditField
        X2EditFieldLabel           matlab.ui.control.Label
        Y1EditFieldCrop            matlab.ui.control.NumericEditField
        Y1EditFieldLabel           matlab.ui.control.Label
        X1EditFieldCrop            matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        HEIGHTEditField            matlab.ui.control.NumericEditField
        HEIGHTEditFieldLabel       matlab.ui.control.Label
        WIDTHEditField             matlab.ui.control.NumericEditField
        WIDTHEditFieldLabel        matlab.ui.control.Label
        RESIZEButton               matlab.ui.control.Button
        SHIFTButton                matlab.ui.control.Button
        REFLECTButton              matlab.ui.control.Button
        REFLECTButtonGroup         matlab.ui.container.ButtonGroup
        VERTICALButton             matlab.ui.control.RadioButton
        HORIZONTALButton           matlab.ui.control.RadioButton
        CHOOSEFILEButton           matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        filepath;
        readingfile;
        picture;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CHOOSEFILEButton
        function CHOOSEFILEButtonPushed(app, event)
            [Filename, Pathname] = uigetfile({'*.jpg';'*.png';'*.tif';'*.bmp'},'File Selector');
            if Filename == 0
                warndlg('You did not choose an image file');
            end

            app.filepath = fullfile(Pathname, Filename);
            app.readingfile = imread(app.filepath);
            imwrite(app.readingfile, 'inputImage.png')

            size_image = size(app.readingfile);
            if length(size_image) ~= 3
                zero = zeros(size_image(1), size_image(2), 3);
                for i = 1:size_image(1)
                    for j = 1:size_image(2)
                        zero(i, j, 1) = app.readingfile(i, j);
                        zero(i, j, 2) = app.readingfile(i, j);
                        zero(i, j, 3) = app.readingfile(i, j);
                    end
                end
                app.readingfile = uint8(zero);
            end

            app.INPUTIMAGE.ImageSource = app.readingfile;

        end

        % Image clicked function: INPUTIMAGE
        function INPUTIMAGEImageClicked(app, event)

        end

        % Button pushed function: REFLECTButton
        function REFLECTButtonPushed(app, event)
            if(app.VERTICALButton.Value == 1)
                app.picture=img_reflection(app.readingfile,'vertical');
            end
            if(app.HORIZONTALButton.Value == 1)
                app.picture=img_reflection(app.readingfile,'horizontal');
            end
            imwrite(app.picture,'reflectionImage.png')
            app.OUTPUTIMAGE.ImageSource = 'reflectionImage.png';

        end

        % Button pushed function: SHIFTButton
        function SHIFTButtonPushed(app, event)
            app.picture=img_shift(app.readingfile,app.DOWNEditField.Value,app.RIGHTEditField.Value);
            imwrite(app.picture,'shiftedImage.png')
            app.OUTPUTIMAGE.ImageSource = 'shiftedImage.png';


        end

        % Button pushed function: RESIZEButton
        function RESIZEButtonPushed(app, event)
            app.picture=resize_img(app.readingfile,app.WIDTHEditField.Value,app.WIDTHEditField.Value);
            imwrite(app.picture,'resizedImage.png')
            app.OUTPUTIMAGE.ImageSource = 'resizedImage.png';
        end

        % Button pushed function: RGBHSIButton
        function RGBHSIButtonPushed(app, event)
            app.picture = rgb2hsi_img(app.readingfile);
            imwrite(app.picture,'hsi.png')
            app.OUTPUTIMAGE.ImageSource = 'hsi.png';

        end

        % Button pushed function: CROPButton
        function CROPButtonPushed(app, event)
            app.picture=crop_img(app.readingfile,app.X1EditFieldCrop.Value,app.Y1EditFieldCrop.Value,app.X2EditFieldCrop.Value,app.Y2EditFieldCrop.Value);
            imwrite(app.picture,'cropImaged.png')
            app.OUTPUTIMAGE.ImageSource = 'cropImaged.png';
        end

        % Button pushed function: STRETCHINGButton
        function STRETCHINGButtonPushed(app, event)
            image = app.INPUTIMAGE.ImageSource;
            size_image = size(image);
            image_red = image(:,:,1);
            image_green = image(:,:,2);
            image_blue = image(:,:,3);

            min_red = min(min(image_red));
            max_red = max(max(image_red));
            min_green = min(min(image_green));
            max_green = max(max(image_green));
            min_blue = min(min(image_blue));
            max_blue = max(max(image_blue));

            for i = 1:size_image(1)
                for j = 1:size_image(2)
                    image_reds = ((250 / (max_red - min_red)) * (image_red(i, j) - min_red));
                    image_greens = ((250 / (max_green - min_green)) * (image_green(i, j) - min_green));
                    image_blues = ((250 / (max_blue - min_blue)) * (image_blue(i, j) - min_blue));
                    lastimage(i,j,1) = uint8(image_reds);
                    lastimage(i,j,2) = uint8(image_greens);
                    lastimage(i,j,3) = uint8(image_blues);
                end
            end
            app.OUTPUTIMAGE.ImageSource = lastimage;
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: EQUALIZATIONButton
        function EQUALIZATIONButtonPushed(app, event)
            image = app.INPUTIMAGE.ImageSource;
            size_image = size(image);

            if length(size_image) == 3
                image = rgb2gray(image);
            end

            image_size = size(image);
            image = double(image);

            % Loop for Getting the Histogram of the image
            histogram = zeros(1, 256);
            for i = 1:image_size(1)
                for j = 1:image_size(2)
                    for k = 0:255
                        if image(i, j) == k
                            histogram(k + 1) = histogram(k + 1) + 1;
                        end
                    end
                end
            end

            % Generating PDF out of histogram by diving by total no. of pixels
            pdf = (1 / (image_size(1) * image_size(2))) * histogram;

            % Generating CDF out of PDF
            cdf = zeros(1, 256);
            cdf(1) = pdf(1);
            for i = 2:256
                cdf(i) = cdf(i - 1) + pdf(i);
            end
            cdf = round(255 * cdf);
            ep = zeros(image_size);
            for i = 1:image_size(1)
                for j = 1:image_size(2)
                    t = (image(i, j) + 1);
                    ep(i, j) = cdf(t);
                end
            end

            % Loop for Getting the Histogram of the image
            histogram_2 = zeros(1,256);
            for i = 1:image_size(1)
                for j = 1:image_size(2)
                    for k = 0:255
                        if ep(i,j) == k
                            histogram_2(k + 1) = histogram_2(k + 1) + 1;
                        end
                    end
                end
            end
            size_ep = size(ep);
            zero = zeros(size_ep(1), size_ep(2), 3);
            for i = 1:size_ep(1)
                for j = 1:size_ep(2)
                    zero(i,j,1) = ep(i,j);
                    zero(i,j,2) = ep(i,j);
                    zero(i,j,3) = ep(i,j);
                end
            end

            app.OUTPUTIMAGE.ImageSource = uint8(zero);
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: SETTRANSFERFUNCTIONButton
        function SETTRANSFERFUNCTIONButtonPushed(app, event)
            blacklevel = app.BlackSlider.Value;
            whitelevel = app.WhiteSilder.Value;
            image = app.INPUTIMAGE.ImageSource;
            size_image = size(image);

            b = size(image);
            image = double(image);

            if length(size_image) == 3
                hist1r = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if image(i, j, 1) == k
                                hist1r(k + 1) = hist1r(k + 1) + 1;
                            end
                        end
                    end
                end

                hist1g = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if image(i, j, 2) == k
                                hist1g(k + 1) = hist1g(k + 1) + 1;
                            end
                        end
                    end
                end

                hist1b = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if image(i, j, 3) == k
                                hist1b(k + 1) = hist1b(k + 1) + 1;
                            end
                        end
                    end
                end

                ep1 = image(:, :, 1);
                for i = 1:b(1)
                    for j = 1:b(2)
                        if ep1(i, j) < blacklevel
                            ep1(i, j) = 0;
                        elseif ep1(i, j) > whitelevel
                            ep1(i, j) = 255;
                        end
                        ep(i, j, 1) = ep1(i, j);
                    end
                end

                ep2 = image(:, :, 2);
                for i = 1:b(1)
                    for j = 1:b(2)
                        if ep2(i, j) < blacklevel
                            ep2(i, j)=0;
                        elseif ep2(i, j) > whitelevel
                            ep2(i, j)=255;
                        end
                        ep(i, j, 2) = ep2(i, j);
                    end
                end

                ep3 = image(:, :, 3);
                for i = 1:b(1)
                    for j = 1:b(2)
                        if ep3(i, j) < blacklevel
                            ep3(i, j) = 0;
                        elseif ep3(i, j) > whitelevel
                            ep3(i, j) = 255;
                        end
                        ep(i, j, 3) = ep3(i, j);
                    end
                end
                app.OUTPUTIMAGE.ImageSource = uint8(ep);
            else
                hist1 = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if image(i,j) == k
                                hist1(k+1) = hist1(k + 1) + 1;
                            end
                        end
                    end
                end

                ep = image;
                for i = 1:b(1)
                    for j = 1:b(2)
                        if ep(i, j) < blacklevel
                            ep(i, j) = 0;
                        elseif ep(i, j) > whitelevel
                            ep(i, j) = 255;
                        end
                    end
                end

                hist2 = zeros(1,256);
                for i = 1:b(1)
                    for j = 1:b(2)
                        for k = 0:255
                            if ep(i, j) == k
                                hist2(k + 1) = hist2(k + 1) + 1;
                            end
                        end
                    end
                end
                app.OUTPUTIMAGE.ImageSource = uint8(ep);
            end

            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: AVERAGEButton_2
        function AVERAGEButton_2Pushed(app, event)
            image = imread(app.filepath);

            %             median_filter = ones(3, 3) / 9;
            %             k_average_image = imfilter(image, median_filter);
            %             image_out = cat(3, k_average_image, k_average_image, k_average_image);

            [row, column] = size(image);
            result_image = zeros(row, column);

            for i = 1:row
                for j = 1:column
                    % We set the neighborhood boundaries here
                    row_min = max(1, i - 1);
                    row_max = min(row, i + 1);
                    column_min = max(1, j - 1);
                    column_max = min(column, j + 1);

                    % Now the neighborhood matrix, denoting it by temp
                    temp = image(row_min:row_max, column_min:column_max);

                    % Now the i'th pixel of the output will be the average
                    % of this neighborhood.
                    result_image(i, j) = mean(temp(:));
                end
            end

            result_image = cat(3, result_image, result_image, result_image);

            app.OUTPUTIMAGE.ImageSource = uint8(result_image);
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: MEDIANButton_2
        function MEDIANButton_2Pushed(app, event)
            image = imread(app.filepath);

            [~, ~, numberOfColorChannels] = size(image);
            if numberOfColorChannels > 1
                %                 original_image_red_channel = image(:, :, 1);
                %                 original_image_green_channel = image(:, :, 2);
                %                 original_image_blue_channel = image(:, :, 3);
                %
                %                 red_channel = medfilt2(original_image_red_channel, [3 3]);
                %                 green_channel = medfilt2(original_image_green_channel, [3 3]);
                %                 blue_channel = medfilt2(original_image_blue_channel, [3 3]);
                %
                %                 result_image = cat(3, red_channel, green_channel, blue_channel);
                result_image = median_filter_to_image(image);
            else
                %                 original_image_gray_scale = image(:, :, 1);
                %
                %                 gray_scale = medfilt2(original_image_gray_scale, [3 3]);
                %                 result_image = cat(3, gray_scale, gray_scale, gray_scale);

                result_image = image;
                [row, column] = size(image);
                for i = 2:row-1
                    for j = 2:column-1
                        mat = [image(i - 1, j - 1), image(i - 1, j), image(i - 1, j + 1), ...
                            image(i, j - 1), image(i, j), image(i, j + 1), ...
                            image(i + 1, j - 1), image(i + 1, j), image(i + 1, j + 1)];
                        mat = sort(mat);
                        result_image(i, j) = mat(5);
                    end
                end
            end
            result_image = cat(3, result_image, result_image, result_image);

            app.OUTPUTIMAGE.ImageSource = result_image;
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: MAXButton_2
        function MAXButton_2Pushed(app, event)
            image = imread(app.filepath);

            % PREALLOCATE THE OUTPUT MATRIX
            B = zeros(size(image));

            % PAD THE MATRIX A WITH ZEROS
            modifyA = padarray(image, [1 1]);

            x = (1:3)';
            y = (1:3)';

            for i = 1:size(modifyA, 1) - 2
                for j = 1:size(modifyA, 2) - 2

                    % VECTORIZED METHOD
                    window = reshape(modifyA(i + x - 1, j + y - 1), [], 1);

                    % FIND THE MAXIMUM VALUE IN THE SELECTED WINDOW
                    B(i, j) = max(window);
                end
            end

            % CONVERT THE OUTPUT MATRIX TO 0-255 RANGE IMAGE TYPE
            result_image = cat(3, B, B, B);

            app.OUTPUTIMAGE.ImageSource = uint8(result_image);
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: MINButton_2
        function MINButton_2Pushed(app, event)
            image = imread(app.filepath);

            % PREALLOCATE THE OUTPUT MATRIX
            B = zeros(size(image));

            % PAD THE MATRIX A WITH ZEROS
            modifyA = padarray(image, [1 1]);

            x = (1:3)';
            y = (1:3)';

            for i = 1:size(modifyA, 1) - 2
                for j = 1:size(modifyA, 2) - 2

                    % VECTORIZED METHOD
                    window = reshape(modifyA(i + x - 1, j + y - 1), [], 1);

                    % FIND THE MAXIMUM VALUE IN THE SELECTED WINDOW
                    B(i, j) = min(window);
                end
            end

            % CONVERT THE OUTPUT MATRIX TO 0-255 RANGE IMAGE TYPE
            result_image = cat(3, B, B, B);

            app.OUTPUTIMAGE.ImageSource = uint8(result_image);
            app.OUTPUTIMAGE.Visible = 1;
        end

        % Button pushed function: APPLYA2HButton
        function APPLYA2HButtonPushed(app, event)

            %             image = imread(app.filepath);
            %
            %             figure (1); subplot (2,2,1);
            %             imshow(image); title ('a) original image');
            %
            %
            %             laplacian_mask = [0 1 0; 1 -4 1; 0 1 0];
            %             image_laplacian = conv2(image, laplacian_mask, 'same');
            %             image_laplacian = image + uint8(image_laplacian);
            %             figure (1); subplot(2,2,2);
            %             imshow(image_laplacian ); title ('b) laplacian enhanced image');
            %
            %             b = imadd(image, image_laplacian);
            %             figure (1); subplot (2,2,3);
            %             imshow(b); title ('c) sharpened image');
            %
            %             c = edge (b,'sobel',0.1);
            %             figure (1); subplot (2,2,4);
            %             imshow(c); title ('d) sobel gradient of image');
            %
            %             d = imboxfilt(uint32(c), 5);
            %             figure (2); subplot (2, 2, 1);
            %             imshow(d); title ('e) smoothed of image');
            %
            %             f= immultiply(b, image_laplacian);
            %             figure (2); subplot (2,2,2);
            %             imshow(f); title ('f) mask image');
            %
            %             g= imadd (image, f);
            %             figure (2); subplot (2,2,3);
            %             imshow(g); title ('g) sharpened image');
            %
            %             c=1;
            %             gamma = 5;
            %             h1=double(g);
            %             h=c*(h1.*gamma);
            %             figure (2); subplot (2,2,4);
            %
            %             imshow(h); title ('h) power law transformation');

            Ia = im2double(imread(app.filepath));

            Ib = imfilter(Ia, -fspecial('laplacian', 0.5) * 3);
            Ic = Ia + Ib;
            Id = abs(imfilter(Ia, fspecial('sobel'))) + ...
                abs(imfilter(Ia, fspecial('sobel')'));
            Ie = imfilter(Id, fspecial('average',5));
            If = Ib .* Ie;
            Ig = Ia + If;
            Ih = imadjust(Ig, [], [], 0.5);

            figure
            imshow(Ia, [])
            title('Original image (a)')
            figure
            imshow(Ib, [])
            title('Image in (b)')

            figure
            imshow(Ic, [])
            title('Image in (c)')

            figure
            imshow(Id, [])
            title('Image in (d)')

            figure
            imshow(Ie, [])
            title('Image in (e)')

            figure
            imshow(If, [])
            title('Image in (f)')

            figure
            imshow(Ig, [])
            title('Image in (g)')
            figure
            imshow(Ih, [])
            title('Image in (h)')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CHOOSEFILEButton = uibutton(app.UIFigure, 'push');
            app.CHOOSEFILEButton.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEFILEButtonPushed, true);
            app.CHOOSEFILEButton.Text = 'CHOOSE FILE';
            app.REFLECTButtonGroup = uibuttongroup(app.UIFigure);
            app.REFLECTButtonGroup.Title = 'REFLECT';
            app.HORIZONTALButton = uiradiobutton(app.REFLECTButtonGroup);
            app.HORIZONTALButton.Text = 'HORIZONTAL';
            app.HORIZONTALButton.Value = true;
            app.VERTICALButton = uiradiobutton(app.REFLECTButtonGroup);
            app.VERTICALButton.Text = 'VERTICAL';
            app.REFLECTButton = uibutton(app.UIFigure, 'push');
            app.REFLECTButton.ButtonPushedFcn = createCallbackFcn(app, @REFLECTButtonPushed, true);
            app.REFLECTButton.Text = 'REFLECT';
            app.SHIFTButton = uibutton(app.UIFigure, 'push');
            app.SHIFTButton.ButtonPushedFcn = createCallbackFcn(app, @SHIFTButtonPushed, true);
            app.SHIFTButton.Text = 'SHIFT';
            app.RESIZEButton = uibutton(app.UIFigure, 'push');
            app.RESIZEButton.ButtonPushedFcn = createCallbackFcn(app, @RESIZEButtonPushed, true);
            app.RESIZEButton.Text = 'RESIZE';
            app.WIDTHEditFieldLabel = uilabel(app.UIFigure);
            app.WIDTHEditFieldLabel.Text = 'WIDTH ';
            app.WIDTHEditField = uieditfield(app.UIFigure, 'numeric');
            app.WIDTHEditField.Limits = [1 Inf];
            app.WIDTHEditField.Value = 1;
            app.HEIGHTEditFieldLabel = uilabel(app.UIFigure);
            app.HEIGHTEditFieldLabel.Text = 'HEIGHT';
            app.HEIGHTEditField = uieditfield(app.UIFigure, 'numeric');
            app.HEIGHTEditField.Limits = [1 Inf];
            app.HEIGHTEditField.Value = 1;
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditFieldCrop = uieditfield(app.UIFigure, 'numeric');
            app.X1EditFieldCrop.Limits = [1 Inf];
            app.X1EditFieldCrop.Value = 1;
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditFieldCrop = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditFieldCrop.Limits = [1 Inf];
            app.Y1EditFieldCrop.Value = 1;
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditFieldCrop = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldCrop.Limits = [1 Inf];
            app.X2EditFieldCrop.Value = 1;
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditFieldCrop = uieditfield(app.UIFigure, 'numeric');
            app.Y2EditFieldCrop.Limits = [1 Inf];
            app.Y2EditFieldCrop.Value = 1;
            app.CROPButton = uibutton(app.UIFigure, 'push');
            app.CROPButton.ButtonPushedFcn = createCallbackFcn(app, @CROPButtonPushed, true);
            app.CROPButton.Text = 'CROP';
            app.RGBHSIButton = uibutton(app.UIFigure, 'push');
            app.RGBHSIButton.ButtonPushedFcn = createCallbackFcn(app, @RGBHSIButtonPushed, true);
            app.RGBHSIButton.Text = 'RGB -> HSI';
            app.WhiteSilderSliderLabel = uilabel(app.UIFigure);
            app.WhiteSilderSliderLabel.Text = 'White Silder';
            app.WhiteSilder = uislider(app.UIFigure);
            app.WhiteSilder.Limits = [0 255];
            app.BlackSliderLabel = uilabel(app.UIFigure);
            app.BlackSliderLabel.Text = 'Black Slider';
            app.BlackSlider = uislider(app.UIFigure);
            app.BlackSlider.Limits = [0 255];
            app.STRETCHINGButton = uibutton(app.UIFigure, 'push');
            app.STRETCHINGButton.ButtonPushedFcn = createCallbackFcn(app, @STRETCHINGButtonPushed, true);
            app.STRETCHINGButton.Text = 'STRETCHING';
            app.EQUALIZATIONButton = uibutton(app.UIFigure, 'push');
            app.EQUALIZATIONButton.ButtonPushedFcn = createCallbackFcn(app, @EQUALIZATIONButtonPushed, true);
            app.EQUALIZATIONButton.Text = 'EQUALIZATION';
            app.SHIFTButtonGroup = uibuttongroup(app.UIFigure);
            app.SHIFTButtonGroup.Title = 'SHIFT';
            app.RIGHTButton = uiradiobutton(app.SHIFTButtonGroup);
            app.RIGHTButton.Text = 'RIGHT';
            app.RIGHTButton.Value = true;
            app.LEFTButton = uiradiobutton(app.SHIFTButtonGroup);
            app.LEFTButton.Text = 'LEFT';
            app.UPButton = uiradiobutton(app.SHIFTButtonGroup);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uiradiobutton(app.SHIFTButtonGroup);
            app.DOWNButton.Text = 'DOWN';
            app.EditFieldShift = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldShift.Limits = [1 Inf];
            app.EditFieldShift.Value = 1;
            app.SETTRANSFERFUNCTIONButton = uibutton(app.UIFigure, 'push');
            app.SETTRANSFERFUNCTIONButton.ButtonPushedFcn = createCallbackFcn(app, @SETTRANSFERFUNCTIONButtonPushed, true);
            app.SETTRANSFERFUNCTIONButton.Text = 'SET TRANSFER FUNCTION';
            app.INPUTIMAGE = uiimage(app.UIFigure);
            app.INPUTIMAGE.ImageClickedFcn = createCallbackFcn(app, @INPUTIMAGEImageClicked, true);
            app.INPUTIMAGELabel = uilabel(app.UIFigure);
            app.INPUTIMAGELabel.Text = 'INPUT IMAGE';
            app.OUTPUTIMAGELabel = uilabel(app.UIFigure);
            app.OUTPUTIMAGELabel.Text = 'OUTPUT IMAGE';
            app.OUTPUTIMAGE = uiimage(app.UIFigure);
            app.FILTERLabel_2 = uilabel(app.UIFigure);
            app.FILTERLabel_2.Text = 'FILTER';
            app.MINButton_2 = uibutton(app.UIFigure, 'push');
            app.MINButton_2.ButtonPushedFcn = createCallbackFcn(app, @MINButton_2Pushed, true);
            app.MINButton_2.Text = 'MIN';
            app.MAXButton_2 = uibutton(app.UIFigure, 'push');
            app.MAXButton_2.ButtonPushedFcn = createCallbackFcn(app, @MAXButton_2Pushed, true);
            app.MAXButton_2.Text = 'MAX';
            app.AVERAGEButton_2 = uibutton(app.UIFigure, 'push');
            app.AVERAGEButton_2.ButtonPushedFcn = createCallbackFcn(app, @AVERAGEButton_2Pushed, true);
            app.AVERAGEButton_2.Text = 'AVERAGE';
            app.MEDIANButton_2 = uibutton(app.UIFigure, 'push');
            app.MEDIANButton_2.ButtonPushedFcn = createCallbackFcn(app, @MEDIANButton_2Pushed, true);
            app.MEDIANButton_2.Text = 'MEDIAN';
            app.APPLYA2HButton = uibutton(app.UIFigure, 'push');
            app.APPLYA2HButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYA2HButtonPushed, true);
            app.APPLYA2HButton.Text = 'APPLY A2H';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end